classdef ParkingMeterGUI < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ParkingMeterUIFigure  matlab.ui.Figure
    ImageBackground       matlab.ui.control.Image
    GridLayout            matlab.ui.container.GridLayout
    GridLeft              matlab.ui.container.GridLayout
    GridLeftCorner        matlab.ui.container.GridLayout
    ImageCashBoxKey       matlab.ui.control.Image
    MasterSwitchLabel     matlab.ui.control.Label
    SwitchMaster          matlab.ui.control.Switch
    ImageInstructions     matlab.ui.control.Image
    GridCenter            matlab.ui.container.GridLayout
    ImageCoinsChange      matlab.ui.control.Image
    GridCoinsChange       matlab.ui.container.GridLayout
    ImageCoinSlot_1       matlab.ui.control.Image
    ImageCoinSlot_2       matlab.ui.control.Image
    ImageCoinSlot_3       matlab.ui.control.Image
    ImageCoinSlot_4       matlab.ui.control.Image
    ImageCoinSlot_5       matlab.ui.control.Image
    ImageCoinSlot_6       matlab.ui.control.Image
    ImageCoinSlot_7       matlab.ui.control.Image
    ImageCoinSlot_8       matlab.ui.control.Image
    ImageCoinSlot_9       matlab.ui.control.Image
    ImageCoinSlot_10      matlab.ui.control.Image
    ImageCoinSlot_11      matlab.ui.control.Image
    ImageCoinSlot_12      matlab.ui.control.Image
    GridMiddle            matlab.ui.container.GridLayout
    GridLedsButtons       matlab.ui.container.GridLayout
    GridLeds              matlab.ui.container.GridLayout
    GivechangeLampLabel   matlab.ui.control.Label
    LedGiveChange         matlab.ui.control.Lamp
    AddcoinsLampLabel     matlab.ui.control.Label
    LedAddCoins           matlab.ui.control.Lamp
    GridButtons           matlab.ui.container.GridLayout
    ButtonRed             matlab.ui.control.Button
    ButtonGreen           matlab.ui.control.Button
    GridTicketLabel       matlab.ui.container.GridLayout
    LabelCoinsChange      matlab.ui.control.Label
    GridTicket            matlab.ui.container.GridLayout
    GridLabel             matlab.ui.container.GridLayout
    ImageTicket           matlab.ui.control.Image
    GridDisplaysFlap      matlab.ui.container.GridLayout
    GridDisplays          matlab.ui.container.GridLayout
    ImageFlap             matlab.ui.control.Image
    GridCoins             matlab.ui.container.GridLayout
    ImageCoin_010         matlab.ui.control.Image
    ImageCoin_020         matlab.ui.control.Image
    ImageCoin_050         matlab.ui.control.Image
    ImageCoin_100         matlab.ui.control.Image
    ImageCoin_200         matlab.ui.control.Image
  end

  properties (Constant = true)
    % Internal App timer duration
    TIMER_PERIOD = 0.01;
    % Time delay when interacting with the GUI
    TIME_COINS_INSERTED = 0.25;
    TIME_TICKET_PRINT = 0.05;
    % 7-segment displays parameters
    BACKGROUND_COLOR = [0.5 0.5 0]; % RGB
    SEGMENTS_COLOR = [131 131 0]/255; % RGB
    % Number of coins for each coins types present in the cash box
    NB_COINS_PER_TYPE = 5;
    NB_COINS_TYPES = 4;
    NB_COINS_SLOTS = 12;
  end

  properties (Access = private)
    Const
    % Ticket's parameter
    TicketLength
    TicketPixelsInc = 10;
    TicketTagLabel = '';
    % Object to Group the three 7-segment displays
    DisplaysGroup
    % Old HW registers values (to check if any App components refresh is needed)
    OLD_DISPLAY_REGISTER_1 = uint8(0);
    OLD_DISPLAY_REGISTER_2 = uint8(0);
    OLD_DISPLAY_REGISTER_3 = uint8(0);
    OLD_PERIPHERALS_REGISTER = uint8(0);
    OLD_BUTTONS_REGISTER = uint8(0);
    OLD_INSERTED_COINS_REGISTER = uint8(0);
    OLD_AVAILABLE_COINS_REGISTER = uint8(0);
    OLD_CASH_BOX_COINS = uint8(0);
  end

  properties (Access = public)
    % Main timer of the App to check emulated registers content
    MainTimer
    % Emulated HW registers
    NEW_DISPLAY_REGISTER_1 = uint8(0);
    NEW_DISPLAY_REGISTER_2 = uint8(0);
    NEW_DISPLAY_REGISTER_3 = uint8(0);
    NEW_PERIPHERALS_REGISTER = uint8(0);
    NEW_BUTTONS_REGISTER = uint8(0);
    NEW_INSERTED_COINS_REGISTER = uint8(0);
    NEW_AVAILABLE_COINS_REGISTER = uint8(0);
    % Physical cash box of the parking meter
    NEW_CASH_BOX_COINS = uint8(0);
    % Indicates if the App is running or not
    Running = 'off';
    % Set the unitTest gobal variable in the test script calling the App
    AutoUnitTesting = false;
    AutoTestingTrace = {'DISP','COIN'};
  end
  
  methods (Access = private)
    function [] = TimerMain(app)
      if (app.AutoUnitTesting)
        % Get all last registers values from the shared memory area
        app.NEW_DISPLAY_REGISTER_1 = read_register(app.Const.DISPLAY_REGISTER_1);
        app.NEW_DISPLAY_REGISTER_2 = read_register(app.Const.DISPLAY_REGISTER_2);
        app.NEW_DISPLAY_REGISTER_3 = read_register(app.Const.DISPLAY_REGISTER_3);
        app.NEW_PERIPHERALS_REGISTER = read_register(app.Const.PERIPHERALS_REGISTER);
        app.NEW_INSERTED_COINS_REGISTER = read_register(app.Const.INSERTED_COINS_REGISTER);
        app.NEW_AVAILABLE_COINS_REGISTER = read_register(app.Const.AVAILABLE_COINS_REGISTER);
        % Check if any new values are displayed in the 7-segment displays
        if ((app.NEW_DISPLAY_REGISTER_1 ~= app.OLD_DISPLAY_REGISTER_1) ||...
            (app.NEW_DISPLAY_REGISTER_2 ~= app.OLD_DISPLAY_REGISTER_2) ||...
            (app.NEW_DISPLAY_REGISTER_3 ~= app.OLD_DISPLAY_REGISTER_3))
          digits = [app.NEW_DISPLAY_REGISTER_3...
                    app.NEW_DISPLAY_REGISTER_2...
                    app.NEW_DISPLAY_REGISTER_1];
          % Retrieve the current displayed amount of money
          DecodeDisplaysRegisters(app,digits);
        end
      end
      % Refresh the first 7-segment display
      if (app.NEW_DISPLAY_REGISTER_1 ~= app.OLD_DISPLAY_REGISTER_1)
        setSevenSegmentDisplay(app.DisplaysGroup,app.NEW_DISPLAY_REGISTER_1,1);
        app.OLD_DISPLAY_REGISTER_1 = app.NEW_DISPLAY_REGISTER_1;
      end
      % Refresh the second 7-segment display
      if (app.NEW_DISPLAY_REGISTER_2 ~= app.OLD_DISPLAY_REGISTER_2)
        setSevenSegmentDisplay(app.DisplaysGroup,app.NEW_DISPLAY_REGISTER_2,2);
        app.OLD_DISPLAY_REGISTER_2 = app.NEW_DISPLAY_REGISTER_2;
      end
      % Refresh the third 7-segment display
      if (app.NEW_DISPLAY_REGISTER_3 ~= app.OLD_DISPLAY_REGISTER_3)
        setSevenSegmentDisplay(app.DisplaysGroup,app.NEW_DISPLAY_REGISTER_3,3);
        app.OLD_DISPLAY_REGISTER_3 = app.NEW_DISPLAY_REGISTER_3;
      end
      % Refresh any peripheral components
      if (app.NEW_PERIPHERALS_REGISTER ~= app.OLD_PERIPHERALS_REGISTER)
        % Refresh the ticket printing status
        if (bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_PRINT_TICKET) &&...
            ~bitand(app.OLD_PERIPHERALS_REGISTER,app.Const.BIT_MASK_PRINT_TICKET))
          % Disable the visibility of the ticket before updating its content
          app.GridTicket.Visible = 'off';
          % Create the date and time tag to add to the bottom of the ticket
          app.TicketTagLabel.Text = datestr(datetime(now+1/24,'ConvertFrom','datenum'));
          app.TicketTagLabel.Layout.Row = 2;
          app.TicketTagLabel.Layout.Column = 1;
          % Print the ticket with a rolling effect
          for k=app.TicketLength:-app.TicketPixelsInc:0
            pause(app.TIME_TICKET_PRINT);
            app.GridTicket.RowHeight(2) = {k};
            if (k == app.TicketLength)
              app.GridTicket.Visible = 'on';
            end
          end
        end
        % Refresh the flap and coins status
        if bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_COINS_FLAP)
          app.ImageFlap.Visible = 'off';
          app.ImageCoin_010.Enable = 'on';
          app.ImageCoin_020.Enable = 'on';
          app.ImageCoin_050.Enable = 'on';
          app.ImageCoin_100.Enable = 'on';
          app.ImageCoin_200.Enable = 'on';
        else
          app.ImageFlap.Visible = 'on';
          app.ImageCoin_010.Enable = 'off';
          app.ImageCoin_020.Enable = 'off';
          app.ImageCoin_050.Enable = 'off';
          app.ImageCoin_100.Enable = 'off';
          app.ImageCoin_200.Enable = 'off';
        end
        % Check if there are coins to give back
        if bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_CHANGE_COINS)
          % Check if a coin's slot is available
          for k=1:1:app.NB_COINS_SLOTS
            evalc(['slotKfree = isempty(app.ImageCoinSlot_' num2str(k) '.ImageSource)']);
            if (slotKfree)
              idx = num2str(k);
              break;
            end
          end
          CoinsIdx = 0;
          % Refresh the 100 cents coin for change status
          if (bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_100_CENTS) && app.NEW_CASH_BOX_COINS(4) > 0)
            CoinsChange = 'Coins_100.png';
            CoinsIdx = 4;
          end
          % Refresh the 50 cents coin for change status
          if (bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_050_CENTS) && app.NEW_CASH_BOX_COINS(3) > 0)
            CoinsChange = 'Coins_50.png';
            CoinsIdx = 3;
          end
          % Refresh the 20 cents coin for change status
          if (bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_020_CENTS) && app.NEW_CASH_BOX_COINS(2) > 0)
            CoinsChange = 'Coins_20.png';
            CoinsIdx = 2;
          end
          % Refresh the 10 cents coin for change status
          if (bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_010_CENTS) && app.NEW_CASH_BOX_COINS(1) > 0)
            CoinsChange = 'Coins_10.png';
            CoinsIdx = 1;
          end
          if (CoinsIdx > 0)
            app.NEW_CASH_BOX_COINS(CoinsIdx) = app.NEW_CASH_BOX_COINS(CoinsIdx)-1;
            if (slotKfree)
              evalc(['app.ImageCoinSlot_' idx '.ImageSource = ''' CoinsChange '''']);
              evalc(['app.ImageCoinSlot_' idx '.Visible = ''on''']);
            end
          end
          % Reset status of the inserted coins
          app.NEW_INSERTED_COINS_REGISTER = bitand(app.NEW_INSERTED_COINS_REGISTER,bitcmp(0x1F));
          if (app.AutoUnitTesting)
            write_register(app.Const.INSERTED_COINS_REGISTER,app.NEW_INSERTED_COINS_REGISTER);
          end
        end
        % Save current peripheral register status
        app.OLD_PERIPHERALS_REGISTER = app.NEW_PERIPHERALS_REGISTER;
        % Reset status of the green and red pressed buttons
        app.NEW_BUTTONS_REGISTER = bitand(app.NEW_BUTTONS_REGISTER,bitcmp(app.Const.BIT_MASK_GREEN_BUTTON));
        app.NEW_BUTTONS_REGISTER = bitand(app.NEW_BUTTONS_REGISTER,bitcmp(app.Const.BIT_MASK_RED_BUTTON));
        if (app.AutoUnitTesting)
          write_register(app.Const.BUTTONS_REGISTER,app.NEW_BUTTONS_REGISTER);
        end
      end
      if (app.AutoUnitTesting)
        % Check if any change has been given back to the user
        cashBoxIdx = (app.NEW_CASH_BOX_COINS ~= app.OLD_CASH_BOX_COINS);
        if any(cashBoxIdx)
          switch sum(cashBoxIdx .* [1 2 3 4])
            case 1
              changeChar = '0.10';
            case 2
              changeChar = '0.20';
            case 3
              changeChar = '0.50';
            case 4
              changeChar = '1.00';
            otherwise
              changeChar = '0.00';
          end
          % Store the amount of change for assessment
          app.AutoTestingTrace = [app.AutoTestingTrace;' ',{changeChar}];
        end
      end
      % Refresh the cash box status
      if ((sum(app.NEW_CASH_BOX_COINS) ~= sum(app.OLD_CASH_BOX_COINS)) && any(app.NEW_CASH_BOX_COINS == 0))
        idxEmpty = find(app.NEW_CASH_BOX_COINS == 0);
        app.NEW_AVAILABLE_COINS_REGISTER = app.Const.BIT_MASK_CHANGE_COINS-sum(uint8(2.^(idxEmpty-1)));
        if (app.NEW_AVAILABLE_COINS_REGISTER ~= app.OLD_AVAILABLE_COINS_REGISTER)
          app.NEW_PERIPHERALS_REGISTER = bitor(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_ADD_LED);
          app.NEW_PERIPHERALS_REGISTER = bitand(app.NEW_PERIPHERALS_REGISTER,bitcmp(app.Const.BIT_MASK_RETURN_LED));
          % Refresh Give change LED
          if bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_RETURN_LED)
            app.LedGiveChange.Color = [0 1 0];
          else
            app.LedGiveChange.Color = [0.5 0.5 0.5];
          end
          % Refresh Add coins LED
          if bitand(app.NEW_PERIPHERALS_REGISTER,app.Const.BIT_MASK_ADD_LED)
            app.LedAddCoins.Color = [1 0.5 0];
          else
            app.LedAddCoins.Color = [0.5 0.5 0.5];
          end
          % Save current peripheral register status
          app.OLD_PERIPHERALS_REGISTER = app.NEW_PERIPHERALS_REGISTER;
        end
      end
      % Save current change registers status
      app.OLD_CASH_BOX_COINS = app.NEW_CASH_BOX_COINS;
      app.OLD_AVAILABLE_COINS_REGISTER = app.NEW_AVAILABLE_COINS_REGISTER;
    end

    function [] = StateButtonChange(app,bitMask,state)
      if (state)
        % The button is activated
        app.NEW_BUTTONS_REGISTER = bitor(app.NEW_BUTTONS_REGISTER,bitMask);
      else
        % The button is deactivated
        app.NEW_BUTTONS_REGISTER = bitand(app.NEW_BUTTONS_REGISTER,bitcmp(bitMask));
      end
      if (app.AutoUnitTesting)
        write_register(app.Const.BUTTONS_REGISTER,app.NEW_BUTTONS_REGISTER);
      end
    end
    
    function [] = InsertCoinEffect(app,coinId)
      % Coin image push effect
      evalc(['app.ImageCoin_' coinId '.Enable = ''off''']);
      evalc(['bitMask = app.Const.BIT_MASK_' coinId '_CENTS']);
      % Coins register's value update
      app.NEW_INSERTED_COINS_REGISTER = bitor(app.NEW_INSERTED_COINS_REGISTER,bitMask);
      if (app.AutoUnitTesting)
        write_register(app.Const.INSERTED_COINS_REGISTER,app.NEW_INSERTED_COINS_REGISTER);
      end
      % Button release effect
      pause(app.TIME_COINS_INSERTED);
      evalc(['app.ImageCoin_' coinId '.Enable = ''on''']);
    end
    
    function [] = CashBoxReset(app)
      % Reset the amount of coins available in the cashbox
      app.NEW_CASH_BOX_COINS = app.NB_COINS_PER_TYPE*ones(1,app.NB_COINS_TYPES,'uint8');
      app.OLD_CASH_BOX_COINS = app.NEW_CASH_BOX_COINS;
      app.NEW_AVAILABLE_COINS_REGISTER = app.Const.BIT_MASK_CHANGE_COINS;
      app.OLD_AVAILABLE_COINS_REGISTER = app.NEW_AVAILABLE_COINS_REGISTER;
      if (app.AutoUnitTesting)
        write_register(app.Const.AVAILABLE_COINS_REGISTER,app.NEW_AVAILABLE_COINS_REGISTER);
      end
      % Leds status when the cash box is fully loaded
      app.LedGiveChange.Color = [0 1 0];
      app.LedAddCoins.Color = [0.5 0.5 0.5];
    end

    function [] = DecodeDisplaysRegisters(app,registersValues)
      % Initialization
      asciiOffset = uint16(0x30);
      nbRegisters = numel(registersValues);
      digitValue = zeros(1,nbRegisters,'uint16');
      digitChar = []; %#ok<*AGROW>
      % Get each display's value and convert it into an integer number
      for k=1:1:nbRegisters
        if ((k == 1) && (registersValues(k) ~= app.Const.SEGMENTS_DIGIT_M))
          % Remove the integer's decimal from the first register's value
          registersValues(k) = registersValues(k)-app.Const.SEGMENTS_DIGIT_P;
        end
        switch (registersValues(k))
          case app.Const.SEGMENTS_DIGIT_0
            digitValue(k) = app.Const.INT_CASE_ENTRY_0+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_1
            digitValue(k) = app.Const.INT_CASE_ENTRY_1+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_2
            digitValue(k) = app.Const.INT_CASE_ENTRY_2+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_3
            digitValue(k) = app.Const.INT_CASE_ENTRY_3+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_4
            digitValue(k) = app.Const.INT_CASE_ENTRY_4+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_5
            digitValue(k) = app.Const.INT_CASE_ENTRY_5+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_6
            digitValue(k) = app.Const.INT_CASE_ENTRY_6+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_7
            digitValue(k) = app.Const.INT_CASE_ENTRY_7+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_8
            digitValue(k) = app.Const.INT_CASE_ENTRY_8+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_9
            digitValue(k) = app.Const.INT_CASE_ENTRY_9+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_M
            digitValue(k) = app.Const.INT_CASE_ENTRY_M;
          otherwise
            % Incorrect 7 segments encoded value
            digitValue(k) = nan;
        end
        % Convert the identified integer into a readable char
        digitChar = [digitChar char(digitValue(k))];
        if (k == 1 && (registersValues(k) ~= app.Const.SEGMENTS_DIGIT_M))
          digitChar = [digitChar '.'];
        end
      end
      % Store the displayed amount values for assessment
      app.AutoTestingTrace = [app.AutoTestingTrace;{digitChar},' '];
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function StartupFcn(app)
      % Command to instanciate the object in the workspace to share data with the low-level MATLAB algorithmic part
      % app = ParkingMeterGUI;
      global UNIT_TESTING;
      app.AutoUnitTesting = UNIT_TESTING;
      % Instanciate system's constants
      app.Const = Constants;
      % Get the master switch status
      SwitchMasterValueChanged(app);
      % Initialization of the leds and the cash box content
      CashBoxReset(app);
      % Initialization of the ticket's length
      ticketLengthCell = app.GridTicket.RowHeight(2);
      app.TicketLength = ticketLengthCell{:};
      app.TicketTagLabel = uilabel(app.GridLabel,'Text','',...
                                   'HorizontalAlignment','center',...
                                   'VerticalAlignment','center',...
                                   'FontSize',12,'FontWeight','bold');
      % Instanciate one 7-Segment displays component
      app.DisplaysGroup = SevenSegmentDisplays(app.Const.NB_DISPLAYS,app.GridDisplays,...
                                               app.SEGMENTS_COLOR,app.BACKGROUND_COLOR);
      % Change segments color to black for the normal App behavior and order the displays to the right
      app.DisplaysGroup.SegmentsColor = [0 0 0];
      app.DisplaysGroup.Endianness = 'Right';
      % Setup of the timer to poll status or change values of the emulated HW registers
      app.MainTimer = timer('Period',app.TIMER_PERIOD,...
                            'ExecutionMode','fixedRate','Name','AppTimer');
      % Function that is periodically called from the timer
      app.MainTimer.TimerFcn = @(~,~)TimerMain(app);
      start(app.MainTimer);
      % Instanciation of the App done
      app.Running = 'on';
    end

    % Image clicked function: ImageCashBoxKey
    function ImageCashBoxKeyImageClicked(app, event)
      app.ImageCashBoxKey.ImageSource = 'Key90.png';
      messageText = 'The cask box has been reset properly.';
      titleText = 'Information';
      if (app.AutoUnitTesting)
        app.AutoTestingTrace = [app.AutoTestingTrace;{' '},{' '}];
        d = uiprogressdlg(app.ParkingMeterUIFigure,'Message',messageText,'Title',titleText);
        % Close the dialog automatically not to disturb the automatic unit testing process
        pause(3); close(d);
      else
        uialert(app.ParkingMeterUIFigure,messageText,titleText,'Icon','info');
      end
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      % Reset the leds and the amount of coins available in the cashbox
      CashBoxReset(app);
    end

    % Value changed function: SwitchMaster
    function SwitchMasterValueChanged(app, event)
      % Toggle the master switch
      if strcmp(app.SwitchMaster.Value,'Off')
        StateButtonChange(app,app.Const.BIT_MASK_MASTER_SWITCH,false);
      else
        StateButtonChange(app,app.Const.BIT_MASK_MASTER_SWITCH,true);
      end
    end

    % Button pushed function: ButtonGreen
    function ButtonGreenPushed(app, event)
      % Press the green button
      StateButtonChange(app,app.Const.BIT_MASK_GREEN_BUTTON,true);
    end

    % Button pushed function: ButtonRed
    function ButtonRedPushed(app, event)
      % Press the red button
      StateButtonChange(app,app.Const.BIT_MASK_RED_BUTTON,true);
    end

    % Image clicked function: ImageCoin_200
    function ImageCoin_200ImageClicked(app, event)
      % Insert a coin of 10 cents
      InsertCoinEffect(app,'200');
    end

    % Image clicked function: ImageCoin_100
    function ImageCoin_100ImageClicked(app, event)
      % Insert a coin of 100 cents
      InsertCoinEffect(app,'100');
    end

    % Image clicked function: ImageCoin_050
    function ImageCoin_050ImageClicked(app, event)
      % Insert a coin of 50 cents
      InsertCoinEffect(app,'050');
    end

    % Image clicked function: ImageCoin_020
    function ImageCoin_020ImageClicked(app, event)
      % Insert a coin of 20 cents
      InsertCoinEffect(app,'020');
    end

    % Image clicked function: ImageCoin_010
    function ImageCoin_010ImageClicked(app, event)
      % Insert a coin of 10 cents
      InsertCoinEffect(app,'010');
    end

    % Image clicked function: ImageTicket
    function ImageTicketClicked(app, event)
      % Hide the ticket and reset its length
      app.TicketTagLabel.Text = '';
      app.GridTicket.Visible = 'off';
    end

    % Image clicked function: ImageCoinSlot_1, 
    % ImageCoinSlot_10, ImageCoinSlot_11, ImageCoinSlot_12, 
    % ImageCoinSlot_2, ImageCoinSlot_3, ImageCoinSlot_4, 
    % ImageCoinSlot_5, ImageCoinSlot_6, ImageCoinSlot_7, 
    % ImageCoinSlot_8, ImageCoinSlot_9
    function ImageCoinSlotXImageClicked(app, event)
      for k=1:1:app.NB_COINS_SLOTS
        evalc(['app.ImageCoinSlot_' num2str(k) '.Visible = ''off''']);
        evalc(['app.ImageCoinSlot_' num2str(k) '.ImageSource = ''''']);
      end
    end

    % Close request function: ParkingMeterUIFigure
    function ParkingMeterUIFigureCloseRequest(app, event)
      try
        stop(timerfind);
        delete(timerfind);
      catch
        disp('No timers are running');
      end
      delete(app);
      clc;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.ParkingMeterUIFigure = uifigure('Visible', 'off');
      app.ParkingMeterUIFigure.Name = 'Parking Meter @ MathWorks';
      app.ParkingMeterUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParkingMeterUIFigureCloseRequest, true);
      app.ImageBackground = uiimage(app.ParkingMeterUIFigure);
      app.ImageBackground.ImageSource = 'Parking_Meter.png';
      app.GridLayout = uigridlayout(app.ParkingMeterUIFigure);
      app.GridLeft = uigridlayout(app.GridLayout);
      app.GridLeftCorner = uigridlayout(app.GridLeft);
      app.ImageCashBoxKey = uiimage(app.GridLeftCorner);
      app.ImageCashBoxKey.ImageClickedFcn = createCallbackFcn(app, @ImageCashBoxKeyImageClicked, true);
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      app.MasterSwitchLabel = uilabel(app.GridLeftCorner);
      app.MasterSwitchLabel.Text = 'Master Switch';
      app.SwitchMaster = uiswitch(app.GridLeftCorner, 'slider');
      app.SwitchMaster.ValueChangedFcn = createCallbackFcn(app, @SwitchMasterValueChanged, true);
      app.SwitchMaster.Value = 'On';
      app.ImageInstructions = uiimage(app.GridLeft);
      app.ImageInstructions.ImageSource = 'Parking_Text.png';
      app.GridCenter = uigridlayout(app.GridLayout);
      app.ImageCoinsChange = uiimage(app.GridCenter);
      app.ImageCoinsChange.ImageSource = 'Background_Coins_Change.png';
      app.GridCoinsChange = uigridlayout(app.GridCenter);
      app.ImageCoinSlot_1 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_1.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_1.Visible = 'off';
      app.ImageCoinSlot_2 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_2.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_2.Visible = 'off';
      app.ImageCoinSlot_3 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_3.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_3.Visible = 'off';
      app.ImageCoinSlot_4 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_4.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_4.Visible = 'off';
      app.ImageCoinSlot_5 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_5.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_5.Visible = 'off';
      app.ImageCoinSlot_6 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_6.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_6.Visible = 'off';
      app.ImageCoinSlot_7 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_7.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_7.Visible = 'off';
      app.ImageCoinSlot_8 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_8.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_8.Visible = 'off';
      app.ImageCoinSlot_9 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_9.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_9.Visible = 'off';
      app.ImageCoinSlot_10 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_10.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_10.Visible = 'off';
      app.ImageCoinSlot_11 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_11.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_11.Visible = 'off';
      app.ImageCoinSlot_12 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_12.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_12.Visible = 'off';
      app.GridMiddle = uigridlayout(app.GridCenter);
      app.GridLedsButtons = uigridlayout(app.GridMiddle);
      app.GridLeds = uigridlayout(app.GridLedsButtons);
      app.GivechangeLampLabel = uilabel(app.GridLeds);
      app.GivechangeLampLabel.Text = 'Give change';
      app.LedGiveChange = uilamp(app.GridLeds);
      app.AddcoinsLampLabel = uilabel(app.GridLeds);
      app.AddcoinsLampLabel.Text = 'Add coins';
      app.LedAddCoins = uilamp(app.GridLeds);
      app.GridButtons = uigridlayout(app.GridLedsButtons);
      app.ButtonRed = uibutton(app.GridButtons, 'push');
      app.ButtonRed.ButtonPushedFcn = createCallbackFcn(app, @ButtonRedPushed, true);
      app.ButtonRed.Text = '';
      app.ButtonGreen = uibutton(app.GridButtons, 'push');
      app.ButtonGreen.ButtonPushedFcn = createCallbackFcn(app, @ButtonGreenPushed, true);
      app.ButtonGreen.Text = '';
      app.GridTicketLabel = uigridlayout(app.GridMiddle);
      app.LabelCoinsChange = uilabel(app.GridTicketLabel);
      app.LabelCoinsChange.Text = 'Coins for change';
      app.GridTicket = uigridlayout(app.GridTicketLabel);
      app.GridTicket.Visible = 'off';
      app.GridLabel = uigridlayout(app.GridTicket);
      app.ImageTicket = uiimage(app.GridTicket);
      app.ImageTicket.ImageClickedFcn = createCallbackFcn(app, @ImageTicketClicked, true);
      app.ImageTicket.ImageSource = 'Ticket.png';
      app.GridDisplaysFlap = uigridlayout(app.GridCenter);
      app.GridDisplays = uigridlayout(app.GridDisplaysFlap);
      app.ImageFlap = uiimage(app.GridDisplaysFlap);
      app.ImageFlap.ImageSource = 'Flap.png';
      app.GridCoins = uigridlayout(app.GridLayout);
      app.ImageCoin_010 = uiimage(app.GridCoins);
      app.ImageCoin_010.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_010ImageClicked, true);
      app.ImageCoin_010.Enable = 'off';
      app.ImageCoin_010.ImageSource = 'Coins_10.png';
      app.ImageCoin_020 = uiimage(app.GridCoins);
      app.ImageCoin_020.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_020ImageClicked, true);
      app.ImageCoin_020.Enable = 'off';
      app.ImageCoin_020.ImageSource = 'Coins_20.png';
      app.ImageCoin_050 = uiimage(app.GridCoins);
      app.ImageCoin_050.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_050ImageClicked, true);
      app.ImageCoin_050.Enable = 'off';
      app.ImageCoin_050.ImageSource = 'Coins_50.png';
      app.ImageCoin_100 = uiimage(app.GridCoins);
      app.ImageCoin_100.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_100ImageClicked, true);
      app.ImageCoin_100.Enable = 'off';
      app.ImageCoin_100.ImageSource = 'Coins_100.png';
      app.ImageCoin_200 = uiimage(app.GridCoins);
      app.ImageCoin_200.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_200ImageClicked, true);
      app.ImageCoin_200.Enable = 'off';
      app.ImageCoin_200.ImageSource = 'Coins_200.png';
      % Show the figure after all components are created
      app.ParkingMeterUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ParkingMeterGUI

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ParkingMeterUIFigure)

      % Execute the startup function
      runStartupFcn(app, @StartupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ParkingMeterUIFigure)
    end
  end
end