% description: Copyright 2023 The MathWorks, Inc.
classdef ParkingMeterGUI < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ParkingMeterUIFigure  matlab.ui.Figure
    PanelTicketLabel      matlab.ui.container.Panel
    GridTicket            matlab.ui.container.GridLayout
    ImageTicket           matlab.ui.control.Image
    LabelTag              matlab.ui.container.GridLayout
    GridLayout            matlab.ui.container.GridLayout
    PanelCoinsInsert      matlab.ui.container.Panel
    GridCoinsInsert       matlab.ui.container.GridLayout
    ImageCoin_010         matlab.ui.control.Image
    ImageCoin_020         matlab.ui.control.Image
    ImageCoin_050         matlab.ui.control.Image
    ImageCoin_100         matlab.ui.control.Image
    ImageCoin_200         matlab.ui.control.Image
    PanelCoinsChange      matlab.ui.container.Panel
    GridCoinsChange       matlab.ui.container.GridLayout
    ImageCoinSlot_16      matlab.ui.control.Image
    ImageCoinSlot_15      matlab.ui.control.Image
    ImageCoinSlot_14      matlab.ui.control.Image
    ImageCoinSlot_13      matlab.ui.control.Image
    ImageCoinSlot_12      matlab.ui.control.Image
    ImageCoinSlot_11      matlab.ui.control.Image
    ImageCoinSlot_10      matlab.ui.control.Image
    ImageCoinSlot_9       matlab.ui.control.Image
    ImageCoinSlot_8       matlab.ui.control.Image
    ImageCoinSlot_7       matlab.ui.control.Image
    ImageCoinSlot_6       matlab.ui.control.Image
    ImageCoinSlot_5       matlab.ui.control.Image
    ImageCoinSlot_4       matlab.ui.control.Image
    ImageCoinSlot_3       matlab.ui.control.Image
    ImageCoinSlot_2       matlab.ui.control.Image
    ImageCoinSlot_1       matlab.ui.control.Image
    PanelDisplays         matlab.ui.container.Panel
    GridDisplays          matlab.ui.container.GridLayout
    MasterSwitchLabel     matlab.ui.control.Label
    SwitchMaster          matlab.ui.control.Switch
    LedGiveChange         matlab.ui.control.Lamp
    GivechangeLampLabel   matlab.ui.control.Label
    LedAddCoins           matlab.ui.control.Lamp
    AddcoinsLampLabel     matlab.ui.control.Label
    CoinsforchangeLabel   matlab.ui.control.Label
    ImageFlap             matlab.ui.control.Image
    ButtonGreen           htmlbutton
    ButtonRed             htmlbutton
    ImageCashBoxKey       matlab.ui.control.Image
    ImageInstructions     matlab.ui.control.Image
    ImageBackground       matlab.ui.control.Image
  end

  properties (Constant = true)
    % Internal App timer duration
    TIMER_PERIOD = 0.02;
    % Time delay when interacting with the GUI
    SLEEP_TIME = 0.01;
    TIME_TICKET_PRINT   = 0.02;
    % 7-segment displays parameters
    BACKGROUND_COLOR = [127 127 0]/255; % RGB
    SEGMENTS_COLOR   = [131 131 0]/255; % RGB
    % LEDs parameters
    LED_NO_COLOR = [127 127 127]/255; % RGB
    LED_OK_COLOR = [  0 255   0]/255; % RGB
    LED_KO_COLOR = [255 127   0]/255; % RGB
    % Number of coins for each coins types present in the cash box
    NB_COINS_PER_TYPE = 5;
    NB_COINS_TYPES    = 4;
    NB_COINS_SLOTS    = 16;
    % Y position where the print of the ticket starts
    BASELINE_TICKET_POS = 405;
    % Round buttons images paths
    GREEN_RELEASED_PATH = '.\\img\\GreenReleased.png';
    GREEN_PUSHED_PATH = '.\\img\\GreenPushed.png';
    RED_RELEASED_PATH = '.\\img\\RedReleased.png';
    RED_PUSHED_PATH = '.\\img\\RedPushed.png';
  end

  properties (Access = private)
    Const
    % Ticket's parameter
    TicketLength
    TicketPixelsInc = 10;
    TicketTagLabel = '';
    % Old HW registers values (to check if any App components refresh is needed)
    OLD_DISPLAY_REGISTER_1       = uint8(0);
    OLD_DISPLAY_REGISTER_2       = uint8(0);
    OLD_DISPLAY_REGISTER_3       = uint8(0);
    OLD_PERIPHERALS_REGISTER     = uint8(0);
    OLD_BUTTONS_REGISTER         = uint8(0);
    OLD_INSERTED_COINS_REGISTER  = uint8(0);
    OLD_AVAILABLE_COINS_REGISTER = uint8(0);
    OLD_CASH_BOX_COINS           = uint8(0);
  end

  properties (Access = public)
    % Emulated HW registers
    DISPLAY_REGISTER_1       = uint8(0);
    DISPLAY_REGISTER_2       = uint8(0);
    DISPLAY_REGISTER_3       = uint8(0);
    PERIPHERALS_REGISTER     = uint8(0);
    BUTTONS_REGISTER         = uint8(0);
    INSERTED_COINS_REGISTER  = uint8(0);
    AVAILABLE_COINS_REGISTER = uint8(0);
    % Physical cash box of the parking meter
    CASH_BOX_COINS           = zeros(1,4,'uint8');
    % Object to Group the three 7-segment displays
    DisplaysGroup
    % Main timer of the App to check emulated registers content
    MainTimer
    % Indicates if the App is running or not
    Running = 'off';
    % Set the unitTest gobal variable in the test script calling the App
    AutoUnitTesting = false;
    AutoTestingTrace = {'DISP','COIN'};
  end
  
  methods (Access = private)
    function [] = TimerMain(app)
      if (app.AutoUnitTesting)
        % Get all last registers values from the shared memory area
        app.DISPLAY_REGISTER_1 = read_register(app.Const.DISPLAY_REGISTER_1);
        app.DISPLAY_REGISTER_2 = read_register(app.Const.DISPLAY_REGISTER_2);
        app.DISPLAY_REGISTER_3 = read_register(app.Const.DISPLAY_REGISTER_3);
        app.PERIPHERALS_REGISTER = read_register(app.Const.PERIPHERALS_REGISTER);
        app.INSERTED_COINS_REGISTER = read_register(app.Const.INSERTED_COINS_REGISTER);
        app.AVAILABLE_COINS_REGISTER = read_register(app.Const.AVAILABLE_COINS_REGISTER);
      end
      % Check if any new values are displayed in the 7-segment displays
      if ((app.DISPLAY_REGISTER_1 ~= app.OLD_DISPLAY_REGISTER_1) ||...
          (app.DISPLAY_REGISTER_2 ~= app.OLD_DISPLAY_REGISTER_2) ||...
          (app.DISPLAY_REGISTER_3 ~= app.OLD_DISPLAY_REGISTER_3))
        % Reset status of any currently inserted coin as it was detected
        app.INSERTED_COINS_REGISTER = bitand(app.INSERTED_COINS_REGISTER,bitcmp(0x1F));
        if (app.AutoUnitTesting)
          % Save the new INSERTED_COINS_REGISTER status in shared memory
          write_register(app.Const.INSERTED_COINS_REGISTER,app.INSERTED_COINS_REGISTER);
          digits = [app.DISPLAY_REGISTER_3 app.DISPLAY_REGISTER_2 app.DISPLAY_REGISTER_1];
          % Retrieve the current displayed amount of money
          DecodeDisplaysRegisters(app,digits);
        end
      end
      % Refresh the first 7-segment display
      if (app.DISPLAY_REGISTER_1 ~= app.OLD_DISPLAY_REGISTER_1)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_1,1);
        app.OLD_DISPLAY_REGISTER_1 = app.DISPLAY_REGISTER_1;
        app.Sleep();
      end
      % Refresh the second 7-segment display
      if (app.DISPLAY_REGISTER_2 ~= app.OLD_DISPLAY_REGISTER_2)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_2,2);
        app.OLD_DISPLAY_REGISTER_2 = app.DISPLAY_REGISTER_2;
        app.Sleep();
      end
      % Refresh the third 7-segment display
      if (app.DISPLAY_REGISTER_3 ~= app.OLD_DISPLAY_REGISTER_3)
        setSevenSegmentDisplay(app.DisplaysGroup,app.DISPLAY_REGISTER_3,3);
        app.OLD_DISPLAY_REGISTER_3 = app.DISPLAY_REGISTER_3;
        app.Sleep();
      end
      % Refresh any peripheral components
      if (app.PERIPHERALS_REGISTER ~= app.OLD_PERIPHERALS_REGISTER)
        % Refresh Give change LED
        if bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_RETURN_LED)
          if all(app.LedGiveChange.Color == app.LED_NO_COLOR)
            app.LedGiveChange.Color = app.LED_OK_COLOR;
          end
        else
          app.LedGiveChange.Color = app.LED_NO_COLOR;
        end
        app.Sleep();
        % Refresh Add coins LED
        if bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_ADD_LED)
          if all(app.LedAddCoins.Color == app.LED_NO_COLOR)
            app.LedAddCoins.Color = app.LED_KO_COLOR;
          end
        else
          app.LedAddCoins.Color = app.LED_NO_COLOR;
        end
        app.Sleep();
        % Check if there are coins to give back
        if bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_CHANGE_COINS)
          % Check if a coin's slot is available
          for k=1:1:app.NB_COINS_SLOTS
            evalc(['slotKfree = isempty(app.ImageCoinSlot_' num2str(k) '.ImageSource)']);
            if (slotKfree)
              idx = num2str(k);
              break;
            end
          end
          CoinsIdx = 0;
          % Refresh the 100 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_100_CENTS) && app.CASH_BOX_COINS(4) > 0)
            CoinsChange = 'Coins100.png';
            CoinsIdx = 4;
          end
          % Refresh the 50 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_050_CENTS) && app.CASH_BOX_COINS(3) > 0)
            CoinsChange = 'Coins050.png';
            CoinsIdx = 3;
          end
          % Refresh the 20 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_020_CENTS) && app.CASH_BOX_COINS(2) > 0)
            CoinsChange = 'Coins020.png';
            CoinsIdx = 2;
          end
          % Refresh the 10 cents coin for change status
          if (bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_010_CENTS) && app.CASH_BOX_COINS(1) > 0)
            CoinsChange = 'Coins010.png';
            CoinsIdx = 1;
          end
          if (CoinsIdx > 0)
            app.CASH_BOX_COINS(CoinsIdx) = app.CASH_BOX_COINS(CoinsIdx)-1;
            if (slotKfree)
              evalc(['app.ImageCoinSlot_' idx '.ImageSource = ''' CoinsChange '''']);
              evalc(['app.ImageCoinSlot_' idx '.Visible = ''on''']);
            end
            app.Sleep();
          end
        end
        % Refresh the flap and coins status
        if bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_COINS_FLAP)
          app.ImageFlap.Visible = 'off';
          app.ImageCoin_010.Enable = 'on';
          app.ImageCoin_020.Enable = 'on';
          app.ImageCoin_050.Enable = 'on';
          app.ImageCoin_100.Enable = 'on';
          app.ImageCoin_200.Enable = 'on';
          app.Sleep();
        else
          app.ImageFlap.Visible = 'on';
          app.ImageCoin_010.Enable = 'off';
          app.ImageCoin_020.Enable = 'off';
          app.ImageCoin_050.Enable = 'off';
          app.ImageCoin_100.Enable = 'off';
          app.ImageCoin_200.Enable = 'off';
          app.Sleep();
        end
        % Refresh the ticket printing status
        if (bitand(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_PRINT_TICKET) &&...
            ~bitand(app.OLD_PERIPHERALS_REGISTER,app.Const.BIT_MASK_PRINT_TICKET))
          % Get current date and time and add one hour (1/24) to it
          currentTime = datetime('now','Format','dd/MM/uuuu HH:mm:ss');
          ticketTagChar = char(currentTime+duration([1 0 0]));
          % Format the date and time data on the ticket
          ticketTagText = fliplr(split(string(ticketTagChar(1:end-3)))')';
          % Create the date and time tag to add to the bottom of the ticket
          app.TicketTagLabel = uilabel(app.LabelTag,'Text',ticketTagText,...
                                       'HorizontalAlignment','center',...
                                       'FontName','Calibri','FontSize',14,'FontWeight','bold');
          app.TicketTagLabel.Layout.Row = 2;
          app.TicketTagLabel.Layout.Column = 1;
          app.ImageTicket.Visible = 'on';
          % Print the ticket with a rolling effect
          for k=app.TicketPixelsInc:app.TicketPixelsInc:app.TicketLength
            pause(app.TIME_TICKET_PRINT);
            app.PanelTicketLabel.Position(2) = app.BASELINE_TICKET_POS-k;
            app.PanelTicketLabel.Position(4) = k;
            app.GridTicket.RowHeight(2) = {k};
          end
        end
        % Refresh the cash box status
        if ((sum(app.CASH_BOX_COINS) ~= sum(app.OLD_CASH_BOX_COINS)) && any(app.CASH_BOX_COINS == 0))
          idxEmpty = find(app.CASH_BOX_COINS == 0);
          app.AVAILABLE_COINS_REGISTER = app.Const.BIT_MASK_CHANGE_COINS-sum(uint8(2.^(idxEmpty-1)));
          if (app.AVAILABLE_COINS_REGISTER ~= app.OLD_AVAILABLE_COINS_REGISTER)
            app.PERIPHERALS_REGISTER = bitor(app.PERIPHERALS_REGISTER,app.Const.BIT_MASK_ADD_LED);
            app.PERIPHERALS_REGISTER = bitand(app.PERIPHERALS_REGISTER,bitcmp(app.Const.BIT_MASK_RETURN_LED));
            if (app.AutoUnitTesting)
              % Save the new PERIPHERALS_REGISTER status in shared memory
              write_register(app.Const.PERIPHERALS_REGISTER,app.PERIPHERALS_REGISTER);
            end
          end
        end
        % Save current peripheral register status
        app.OLD_PERIPHERALS_REGISTER = app.PERIPHERALS_REGISTER;
        % Reset status of the green and red pressed buttons
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(app.Const.BIT_MASK_GREEN_BUTTON));
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(app.Const.BIT_MASK_RED_BUTTON));
        if (app.AutoUnitTesting)
          % Save the new BUTTONS_REGISTER status in shared memory
          write_register(app.Const.BUTTONS_REGISTER,app.BUTTONS_REGISTER);
        end
      end
      if (app.AutoUnitTesting)
        % Save the new AVAILABLE_COINS_REGISTER status in shared memory
        write_register(app.Const.AVAILABLE_COINS_REGISTER,app.AVAILABLE_COINS_REGISTER);
        % Check if any change has been given back to the user
        cashBoxIdx = (app.CASH_BOX_COINS ~= app.OLD_CASH_BOX_COINS);
        if any(cashBoxIdx)
          switch sum(cashBoxIdx .* [1 2 3 4])
            case 1
              changeChar = '0.10';
            case 2
              changeChar = '0.20';
            case 3
              changeChar = '0.50';
            case 4
              changeChar = '1.00';
            otherwise
              % Incorrect coins for change
              changeChar = '';
          end
          if ~isempty(changeChar)
            % Store the amount of change for assessment
            app.AutoTestingTrace = [app.AutoTestingTrace;' ',{changeChar}];
          end
        end
      end
      % Save current change registers status
      app.OLD_CASH_BOX_COINS = app.CASH_BOX_COINS;
      app.OLD_AVAILABLE_COINS_REGISTER = app.AVAILABLE_COINS_REGISTER;
      if strcmp(app.ButtonGreen.BackgroundImage,app.GREEN_PUSHED_PATH)
        pause(0.2);
        app.ButtonGreen.BackgroundImage = app.GREEN_RELEASED_PATH;
      elseif strcmp(app.ButtonRed.BackgroundImage,app.RED_PUSHED_PATH)
        pause(0.2);
        app.ButtonRed.BackgroundImage = app.RED_RELEASED_PATH;
      end
    end

    function [] = StateButtonChange(app,bitMask,state)
      if (state)
        % The button is activated
        app.BUTTONS_REGISTER = bitor(app.BUTTONS_REGISTER,bitMask);
      else
        % The button is deactivated
        app.BUTTONS_REGISTER = bitand(app.BUTTONS_REGISTER,bitcmp(bitMask));
      end
      if (app.AutoUnitTesting)
        % Save the new BUTTONS_REGISTER status in shared memory
        write_register(app.Const.BUTTONS_REGISTER,app.BUTTONS_REGISTER);
      end
      app.Sleep();
    end

    function [] = InsertCoinEffect(app,coinId)
      % Coin image push effect
      evalc(['app.ImageCoin_' coinId '.Enable = ''off''']);
      evalc(['bitMask = app.Const.BIT_MASK_' coinId '_CENTS']);
      % Coins register's value update
      app.INSERTED_COINS_REGISTER = bitor(app.INSERTED_COINS_REGISTER,bitMask);
      if (app.AutoUnitTesting)
        % Save the new INSERTED_COINS_REGISTER status in shared memory
        write_register(app.Const.INSERTED_COINS_REGISTER,app.INSERTED_COINS_REGISTER);
      end
      % Button release effect
      app.Sleep();
      evalc(['app.ImageCoin_' coinId '.Enable = ''on''']);
    end

    function [] = CashBoxReset(app)
      % Reset the amount of coins available in the cashbox
      app.CASH_BOX_COINS = app.NB_COINS_PER_TYPE*ones(1,app.NB_COINS_TYPES,'uint8');
      app.OLD_CASH_BOX_COINS = app.CASH_BOX_COINS;
      app.AVAILABLE_COINS_REGISTER = app.Const.BIT_MASK_CHANGE_COINS;
      app.OLD_AVAILABLE_COINS_REGISTER = app.AVAILABLE_COINS_REGISTER;
      if (app.AutoUnitTesting)
        % Save the new AVAILABLE_COINS_REGISTER status in shared memory
        write_register(app.Const.AVAILABLE_COINS_REGISTER,app.AVAILABLE_COINS_REGISTER);
      end
      % Leds status when the cash box is fully loaded
      app.LedGiveChange.Color = [0 1 0];
      app.LedAddCoins.Color = [0.5 0.5 0.5];
      app.Sleep();
    end

    function [] = DecodeDisplaysRegisters(app,registersValues)
      % Initialization
      asciiOffset = uint16(0x30);
      nbRegisters = numel(registersValues);
      digitValue = zeros(1,nbRegisters,'uint16');
      digitChar = []; %#ok<*AGROW>
      % Get each display's value and convert it into an integer number
      for k=1:1:nbRegisters
        if ((k == 1) && (registersValues(k) ~= app.Const.SEGMENTS_DIGIT_M))
          % Remove the integer's decimal from the first register's value
          registersValues(k) = registersValues(k)-app.Const.SEGMENTS_DIGIT_P;
        end
        switch (registersValues(k))
          case app.Const.SEGMENTS_DIGIT_0
            digitValue(k) = app.Const.INT_CASE_ENTRY_0+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_1
            digitValue(k) = app.Const.INT_CASE_ENTRY_1+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_2
            digitValue(k) = app.Const.INT_CASE_ENTRY_2+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_3
            digitValue(k) = app.Const.INT_CASE_ENTRY_3+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_4
            digitValue(k) = app.Const.INT_CASE_ENTRY_4+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_5
            digitValue(k) = app.Const.INT_CASE_ENTRY_5+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_6
            digitValue(k) = app.Const.INT_CASE_ENTRY_6+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_7
            digitValue(k) = app.Const.INT_CASE_ENTRY_7+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_8
            digitValue(k) = app.Const.INT_CASE_ENTRY_8+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_9
            digitValue(k) = app.Const.INT_CASE_ENTRY_9+asciiOffset;
          case app.Const.SEGMENTS_DIGIT_M
            digitValue(k) = app.Const.INT_CASE_ENTRY_M;
          otherwise
            % Incorrect 7 segments encoded value
            digitValue(k) = nan;
        end
        % Convert the identified integer into a readable char
        digitChar = [digitChar char(digitValue(k))];
        if (k == 1 && (registersValues(k) ~= app.Const.SEGMENTS_DIGIT_M))
          digitChar = [digitChar '.'];
        end
      end
      % Store the displayed amount values for assessment
      app.AutoTestingTrace = [app.AutoTestingTrace;{digitChar},' '];
    end
    
    function [] = Sleep(app)
      pause(app.SLEEP_TIME);
    end
  end

  methods (Access = public)
    function [] = CloseRequest(app)
      % Allow to close the App from the external environment
      app.ParkingMeterUIFigureCloseRequest();
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function StartupFcn(app)
      % Command to instanciate the object in the workspace to share data with the low-level MATLAB algorithmic part
      % app = ParkingMeterGUI;
      global UNIT_TESTING; %#ok<GVMIS> 
      app.AutoUnitTesting = UNIT_TESTING;
      % Instanciate system's constants
      app.Const = Constants;

      % Get the master switch status
      SwitchMasterValueChanged(app);
      % Initialization of the leds and the cash box content
      app.CashBoxReset();
      % Initialization of the ticket's length
      app.TicketLength = app.PanelTicketLabel.Position(4);
      app.ImageTicketClicked();
      app.PanelTicketLabel.Position(2) = app.BASELINE_TICKET_POS;
      % Instanciate one 7-Segment displays component
      app.DisplaysGroup = SevenSegmentDisplays(app.Const.NB_DISPLAYS,app.GridDisplays,...
                                               app.SEGMENTS_COLOR,app.BACKGROUND_COLOR);
      pause(2);
      % Change segments color to black for the normal App behavior and order the displays to the right
      app.DisplaysGroup.SegmentsColor = [0 0 0];
      app.DisplaysGroup.Endianness = 'Right';
      % Setup of the timer to poll status or change values of the emulated HW registers
      app.MainTimer = timer('Period',app.TIMER_PERIOD,'ExecutionMode','fixedRate');
      % Function that is periodically called from the timer
      app.MainTimer.TimerFcn = @(~,~)app.TimerMain();
      start(app.MainTimer);
      % Instanciation of the App done
      app.Running = 'on';
    end

    % Image clicked function: ImageCashBoxKey
    function ImageCashBoxKeyImageClicked(app, event)
      app.ImageCashBoxKey.ImageSource = 'Key90.png';
      messageText = 'The cask box has been reset properly.';
      titleText = 'Information';
      if (app.AutoUnitTesting)
        app.AutoTestingTrace = [app.AutoTestingTrace;{' '},{' '}];
        d = uiprogressdlg(app.ParkingMeterUIFigure,'Message',messageText,...
                          'Title',titleText,'Indeterminate','on');
        % Close the dialog automatically not to disturb the automatic unit testing process
        pause(3); close(d);
      else
        uialert(app.ParkingMeterUIFigure,messageText,titleText,'Icon','info');
        uiwait(app.ParkingMeterUIFigure,1);
      end
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      app.Sleep();
      % Reset the leds and the amount of coins available in the cashbox
      app.CashBoxReset();
    end

    % Value changed function: SwitchMaster
    function SwitchMasterValueChanged(app, event)
      % Toggle the master switch
      if strcmp(app.SwitchMaster.Value,'Off')
        app.StateButtonChange(app.Const.BIT_MASK_MASTER_SWITCH,false);
      else
        app.StateButtonChange(app.Const.BIT_MASK_MASTER_SWITCH,true);
      end
    end

    % Callback function: ButtonGreen
    function ButtonGreenPushed(app, event)
      % Press the green button
      app.ButtonGreen.BackgroundImage = app.GREEN_PUSHED_PATH;
      app.StateButtonChange(app.Const.BIT_MASK_GREEN_BUTTON,true);
    end

    % Callback function: ButtonRed
    function ButtonRedPushed(app, event)
      % Press the red button
      app.ButtonRed.BackgroundImage = app.RED_PUSHED_PATH;
      app.StateButtonChange(app.Const.BIT_MASK_RED_BUTTON,true);
    end

    % Image clicked function: ImageCoin_200
    function ImageCoin_200ImageClicked(app, event)
      % Insert a coin of 10 cents
      app.InsertCoinEffect('200');
    end

    % Image clicked function: ImageCoin_100
    function ImageCoin_100ImageClicked(app, event)
      % Insert a coin of 100 cents
      app.InsertCoinEffect('100');
    end

    % Image clicked function: ImageCoin_050
    function ImageCoin_050ImageClicked(app, event)
      % Insert a coin of 50 cents
      app.InsertCoinEffect('050');
    end

    % Image clicked function: ImageCoin_020
    function ImageCoin_020ImageClicked(app, event)
      % Insert a coin of 20 cents
      app.InsertCoinEffect('020');
    end

    % Image clicked function: ImageCoin_010
    function ImageCoin_010ImageClicked(app, event)
      % Insert a coin of 10 cents
      app.InsertCoinEffect('010');
    end

    % Image clicked function: ImageTicket
    function ImageTicketClicked(app, event)
      % Hide the ticket and reset its length
      app.TicketTagLabel.Text = '';
      app.ImageTicket.Visible = 'off';
      app.PanelTicketLabel.Position(4) = 0;
      app.GridTicket.RowHeight(2) = {0};
      app.Sleep();
    end

    % Image clicked function: ImageCoinSlot_1, ImageCoinSlot_10, 
    % ...and 10 other components
    function ImageCoinSlotXImageClicked(app, event)
      for k=1:1:app.NB_COINS_SLOTS
        evalc(['app.ImageCoinSlot_' num2str(k) '.Visible = ''off''']);
        evalc(['app.ImageCoinSlot_' num2str(k) '.ImageSource = ''''']);
      end
      app.Sleep();
    end

    % Close request function: ParkingMeterUIFigure
    function ParkingMeterUIFigureCloseRequest(app, event)
      try
        % Kill any remaining timers
        stop(timerfind);
        delete(timerfind);
      catch
        disp('No timers are running');
      end
      % Delete the app instance and the workspace's contents
      delete(app);
      evalin('base','clearvars');
      evalin('base','clc');
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.ParkingMeterUIFigure = uifigure('Visible', 'off');
      app.ParkingMeterUIFigure.Name = 'Parking Meter @ MathWorks';
      app.ParkingMeterUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParkingMeterUIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.ParkingMeterUIFigure);
      app.ImageBackground = uiimage(app.GridLayout);
      app.ImageBackground.ImageSource = fullfile(pathToMLAPP, 'Resources', 'ParkingMeter.png');
      app.ImageInstructions = uiimage(app.GridLayout);
      app.ImageInstructions.ImageSource = fullfile(pathToMLAPP, 'Resources', 'ParkingText.png');
      app.ImageCashBoxKey = uiimage(app.GridLayout);
      app.ImageCashBoxKey.ImageClickedFcn = createCallbackFcn(app, @ImageCashBoxKeyImageClicked, true);
      app.ImageCashBoxKey.ImageSource = 'Key.png';
      app.ButtonRed = htmlbutton(app.GridLayout);
      app.ButtonRed.BackgroundImage = ".\\img\\RedReleased.png";
      app.ButtonRed.BorderBottomThickness = 0;
      app.ButtonRed.BorderLeftThickness = 0;
      app.ButtonRed.BorderRightThickness = 0;
      app.ButtonRed.BorderTopThickness = 0;
      app.ButtonRed.BorderRadius = 40;
      app.ButtonRed.Text = '';
      app.ButtonRed.ButtonPushedFcn = createCallbackFcn(app, @ButtonRedPushed, true);
      app.ButtonGreen = htmlbutton(app.GridLayout);
      app.ButtonGreen.BackgroundImage = ".\\img\\GreenReleased.png";
      app.ButtonGreen.BorderBottomThickness = 0;
      app.ButtonGreen.BorderLeftThickness = 0;
      app.ButtonGreen.BorderRightThickness = 0;
      app.ButtonGreen.BorderTopThickness = 0;
      app.ButtonGreen.BorderRadius = 40;
      app.ButtonGreen.Text = '';
      app.ButtonGreen.ButtonPushedFcn = createCallbackFcn(app, @ButtonGreenPushed, true);
      app.ImageFlap = uiimage(app.GridLayout);
      app.ImageFlap.ImageSource = 'Flap.png';
      app.CoinsforchangeLabel = uilabel(app.GridLayout);
      app.CoinsforchangeLabel.Text = 'Coins for change';
      app.AddcoinsLampLabel = uilabel(app.GridLayout);
      app.AddcoinsLampLabel.Text = 'Add coins';
      app.LedAddCoins = uilamp(app.GridLayout);
      app.GivechangeLampLabel = uilabel(app.GridLayout);
      app.GivechangeLampLabel.Text = 'Give change';
      app.LedGiveChange = uilamp(app.GridLayout);
      app.SwitchMaster = uiswitch(app.GridLayout, 'slider');
      app.SwitchMaster.ValueChangedFcn = createCallbackFcn(app, @SwitchMasterValueChanged, true);
      app.SwitchMaster.Value = 'On';
      app.MasterSwitchLabel = uilabel(app.GridLayout);
      app.MasterSwitchLabel.Text = 'Master Switch';
      app.PanelDisplays = uipanel(app.GridLayout);
      app.GridDisplays = uigridlayout(app.PanelDisplays);
      app.PanelCoinsChange = uipanel(app.GridLayout);
      app.GridCoinsChange = uigridlayout(app.PanelCoinsChange);
      app.ImageCoinSlot_1 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_1.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_1.Visible = 'off';
      app.ImageCoinSlot_2 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_2.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_2.Visible = 'off';
      app.ImageCoinSlot_3 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_3.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_3.Visible = 'off';
      app.ImageCoinSlot_4 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_4.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_4.Visible = 'off';
      app.ImageCoinSlot_5 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_5.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_5.Visible = 'off';
      app.ImageCoinSlot_6 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_6.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_6.Visible = 'off';
      app.ImageCoinSlot_7 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_7.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_7.Visible = 'off';
      app.ImageCoinSlot_8 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_8.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_8.Visible = 'off';
      app.ImageCoinSlot_9 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_9.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_9.Visible = 'off';
      app.ImageCoinSlot_10 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_10.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_10.Visible = 'off';
      app.ImageCoinSlot_11 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_11.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_11.Visible = 'off';
      app.ImageCoinSlot_12 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_12.ImageClickedFcn = createCallbackFcn(app, @ImageCoinSlotXImageClicked, true);
      app.ImageCoinSlot_12.Visible = 'off';
      app.ImageCoinSlot_13 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_13.Visible = 'off';
      app.ImageCoinSlot_14 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_14.Visible = 'off';
      app.ImageCoinSlot_15 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_15.Visible = 'off';
      app.ImageCoinSlot_16 = uiimage(app.GridCoinsChange);
      app.ImageCoinSlot_16.Visible = 'off';
      app.PanelCoinsInsert = uipanel(app.GridLayout);
      app.GridCoinsInsert = uigridlayout(app.PanelCoinsInsert);
      app.ImageCoin_200 = uiimage(app.GridCoinsInsert);
      app.ImageCoin_200.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_200ImageClicked, true);
      app.ImageCoin_200.Enable = 'off';
      app.ImageCoin_200.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Coins', 'Coins200.png');
      app.ImageCoin_100 = uiimage(app.GridCoinsInsert);
      app.ImageCoin_100.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_100ImageClicked, true);
      app.ImageCoin_100.Enable = 'off';
      app.ImageCoin_100.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Coins', 'Coins100.png');
      app.ImageCoin_050 = uiimage(app.GridCoinsInsert);
      app.ImageCoin_050.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_050ImageClicked, true);
      app.ImageCoin_050.Enable = 'off';
      app.ImageCoin_050.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Coins', 'Coins050.png');
      app.ImageCoin_020 = uiimage(app.GridCoinsInsert);
      app.ImageCoin_020.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_020ImageClicked, true);
      app.ImageCoin_020.Enable = 'off';
      app.ImageCoin_020.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Coins', 'Coins020.png');
      app.ImageCoin_010 = uiimage(app.GridCoinsInsert);
      app.ImageCoin_010.ImageClickedFcn = createCallbackFcn(app, @ImageCoin_010ImageClicked, true);
      app.ImageCoin_010.Enable = 'off';
      app.ImageCoin_010.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Coins', 'Coins010.png');
      app.PanelTicketLabel = uipanel(app.ParkingMeterUIFigure);
      app.GridTicket = uigridlayout(app.PanelTicketLabel);
      app.LabelTag = uigridlayout(app.GridTicket);
      app.ImageTicket = uiimage(app.GridTicket);
      app.ImageTicket.ImageClickedFcn = createCallbackFcn(app, @ImageTicketClicked, true);
      app.ImageTicket.Visible = 'off';
      app.ImageTicket.ImageSource = fullfile(pathToMLAPP, 'Resources', 'Ticket.png');
      % Show the figure after all components are created
      app.ParkingMeterUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ParkingMeterGUI

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ParkingMeterUIFigure)

      % Execute the startup function
      runStartupFcn(app, @StartupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ParkingMeterUIFigure)
    end
  end
end